classdef GUI_plot_components < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TrialsPanel              matlab.ui.container.Panel
        Trial1Axis               matlab.ui.control.UIAxes
        Trial2Axis               matlab.ui.control.UIAxes
        Trial3Axis               matlab.ui.control.UIAxes
        Trial4Axis               matlab.ui.control.UIAxes
        NextTrialsButton         matlab.ui.control.Button
        PreviousTrialsButton     matlab.ui.control.Button
        TimeXTrialPanel          matlab.ui.container.Panel
        Image                    matlab.ui.control.UIAxes
        TopoplotPanel            matlab.ui.container.Panel
        NextComponentButton      matlab.ui.control.Button
        PreviousComponentButton  matlab.ui.control.Button
        TopoAxis                 matlab.ui.control.UIAxes
        SpectrogramPanel         matlab.ui.container.Panel
        SpectrogramAxes          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        trialCount % Number of trials shown so far.
        inputs % Inputs available to all functions.
    end
    
    methods (Access = private)
        
        function app = PlotSpect(app)
            % Spectrogram of the component.
            % To get a better estimation, use the Welch method
            for trialIdx = 1:length(length(app.inputs.trials_ica(:,1)))
                if trialIdx == 1
                    [welchTmp, welchFreq]       = pwelch(app.inputs.trials_ica(trialIdx,:),[],0,[],app.inputs.components.fsample);
                    welchPow                    = zeros(length(app.inputs.trials_ica(:,1)),length(welchFreq));
                    welchPow(trialIdx,:)         = welchTmp;
                else
                    welchPow(trialIdx,:)                    = pwelch(app.inputs.trials_ica(trialIdx,:),[],0,[],app.inputs.components.fsample);
                end
            end
            
            avgWelchPow = mean(welchPow,1);
        
            plot(app.SpectrogramAxes,welchFreq,avgWelchPow);
            xlabel(app.SpectrogramAxes, 'Frequency (Hertz)');
            ylabel(app.SpectrogramAxes, 'Pow (a.u.)');
            
            
        end
        
        function app = PlotTrials(app)
             % And plot the first four single trials.
            tr1                 = app.trialCount.first;
            tr2                 = app.trialCount.second;
            tr3                 = app.trialCount.third;
            tr4                 = app.trialCount.fourth;
            
            currentCompMaxScale     = app.inputs.maxScale(app.inputs.ic); 
            
            currentNumSamples = size(app.inputs.components.trial{tr1},2);
            plot(app.Trial1Axis,app.inputs.components.time{tr1},app.inputs.trials_ica(tr1,1:currentNumSamples))
            title(app.Trial1Axis,['trial # ' num2str(tr1)],'FontSize',10)
            xlim(app.Trial1Axis,[0 app.inputs.maxTrialLength])
            ylim(app.Trial1Axis,[-currentCompMaxScale currentCompMaxScale])
            
            currentNumSamples = size(app.inputs.components.trial{tr2},2);
            plot(app.Trial2Axis,app.inputs.components.time{tr2},app.inputs.trials_ica(tr2,1:currentNumSamples))
            title(app.Trial2Axis,['trial # ' num2str(tr2)],'FontSize',10)
            xlim(app.Trial2Axis,[0 app.inputs.maxTrialLength])
            ylim(app.Trial2Axis,[-currentCompMaxScale currentCompMaxScale])
            
            currentNumSamples = size(app.inputs.components.trial{tr3},2);
            plot(app.Trial3Axis,app.inputs.components.time{tr3},app.inputs.trials_ica(tr3,1:currentNumSamples))
            title(app.Trial3Axis,['trial # ' num2str(tr3)],'FontSize',10)
            xlim(app.Trial3Axis,[0 app.inputs.maxTrialLength])
            ylim(app.Trial3Axis,[-currentCompMaxScale currentCompMaxScale])
            
            currentNumSamples = size(app.inputs.components.trial{tr4},2);
            plot(app.Trial4Axis,app.inputs.components.time{tr4},app.inputs.trials_ica(tr4,1:currentNumSamples))
            title(app.Trial4Axis,['trial # ' num2str(tr4)],'FontSize',10)
            xlim(app.Trial4Axis,[0 app.inputs.maxTrialLength])
            ylim(app.Trial4Axis,[-currentCompMaxScale currentCompMaxScale])
        end
        
        function app = PlotTopo(app)
            % And now the first component topoplot.
            topo_ica = app.inputs.components.topo(:,app.inputs.ic);
    
            cfg                 = []; 
            cfg.component       = [app.inputs.ic]; 
            cfg.layout          = app.inputs.layout;
            cfg.interactive     = 'no';
            cfg.comment         = 'no';
            cfg.title           = 'off';
            figure(1);
            ft_topoplotIC(cfg,app.inputs.components);      % IC topography
            
            % This is ridiculous, but take a frame of the topoplot and copy it into the GUI.
            topoFrame = getframe(1);
            close(1)
            topoImage = frame2im(topoFrame);
            imagesc(app.TopoAxis,topoImage);
            title(app.TopoAxis,['IC #  ' num2str(app.inputs.ic)],'FontWeight','bold','FontSize',12);
            xlim(app.TopoAxis,[0 length(topoImage(1,:,1))]);
            ylim(app.TopoAxis,[0 length(topoImage(:,1))]);
            
        end
        
        function app = PlotTimeByTrial(app)
            imagesc(app.Image,app.inputs.trials_ica);    % x axis: time; y axis: trials)
            title(app.Image,'Trials x Samples','FontWeight','bold','FontSize',12)
            xlim(app.Image,[0 length(app.inputs.trials_ica(1,:))])
            ylim(app.Image,[0 length(app.inputs.trials_ica(:,1))])
            colormap(app.Image,parula);
            colorbar(app.Image)
        end
        
        function GetKeybordInput(app)
            
            % No keyboard inputs yet so use this workaround...
            %             userInput = waitforbuttonpress;
%             userInput = double(get(gcf,'CurrentCharacter'));
%             close(gcf);
            figure('KeyPressFcn',@(obj,evt) 0);
            set(gcf,'pos',[10 10 10 10]);
            waitfor(gcf,'CurrentCharacter');
            userInput=uint8(get(gcf,'CurrentCharacter'));
            close(gcf);
            
            event = [];
            if userInput == 28
                previousTrials(app,event);
            elseif userInput == 29
                nextTrials(app,event);
            elseif userInput == 30
                nextComponent(app,event);
            elseif userInput == 31
                previousComponent(app,event);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, components, layout)
            % Begin setup 
             % Make sure the inputs can be accessed elsewhere.
            app.inputs.components       = components;
            app.inputs.layout           = layout;
            app.inputs.ic              = 1;
            app.inputs.ntr                 = length(app.inputs.components.trial);
            app.inputs.numSamples          = 0;  
            for i = 1:app.inputs.ntr
                app.inputs.numSamples          = max(app.inputs.numSamples,size(app.inputs.components.trial{i},2));
            end
            app.inputs.numComponents       = size(app.inputs.components.label,1);
            
            % 
            app.inputs.trials_ica      = zeros(length(app.inputs.components.trial),app.inputs.numSamples);
            for tr = 1:app.inputs.ntr
                currentNumSamples                   = size(app.inputs.components.trial{tr},2);
                app.inputs.trials_ica(tr,1:currentNumSamples)  = app.inputs.components.trial{tr}(app.inputs.ic,:);
            end
            app.trialCount.first            = 1;
            app.trialCount.second           = 2;
            app.trialCount.third            = 3;
            app.trialCount.fourth           = 4;
            
            
             % Figure out the axes.
            [~, maxTrialLength]     = max(cellfun(@length,app.inputs.components.time));
            app.inputs.maxTrialLength          = app.inputs.components.time{maxTrialLength}(end);
            
            % Save time later by passing this to other functions.
            maxScale = nan(length(app.inputs.components.trial),length(app.inputs.components.trial{1}(:,1)));
            for i = 1:length(app.inputs.components.trial)
                for j = 1:length(app.inputs.components.trial{i}(:,1))
                    maxScale(i,j)       = max(app.inputs.components.trial{i}(j,:));
                end
            end
            
            maxScale                = max(maxScale,[],1);
            app.inputs.maxScale     = maxScale;


            app = PlotTimeByTrial(app);
            
            app = PlotTopo(app);
            
            app = PlotTrials(app);
            
            app = PlotSpect(app);
            
            % No keyboard inputs yet so use this workaround...
%             userInput = waitforbuttonpress;
%             userInput = double(get(gcf,'CurrentCharacter'));
%             close(gcf);
            figure('KeyPressFcn',@(obj,evt) 0);
            set(gcf,'pos',[10 10 10 10]);
            waitfor(gcf,'CurrentCharacter');
            userInput=uint8(get(gcf,'CurrentCharacter'));
            close(gcf);
            
            event = [];
            if userInput == 28
                previousTrials(app,event);
            elseif userInput == 29
                nextTrials(app,event);
            elseif userInput == 30
                nextComponent(app,event);
            elseif userInput == 31
                previousComponent(app,event);
            end
        end

        % Button pushed function: NextTrialsButton
        function nextTrials(app, event)
            % Setup the next 4 trials
            app.trialCount.first                 = app.trialCount.first+4;    
            app.trialCount.second                 = app.trialCount.second+4;
            app.trialCount.third                 = app.trialCount.third+4;
            app.trialCount.fourth                 = app.trialCount.fourth+4;
            
            if app.trialCount.fourth > app.inputs.ntr     % Don't allow the count to go above the ntr.
                app.trialCount.fourth     = app.inputs.ntr;
                app.trialCount.third     = app.inputs.ntr-1;
                app.trialCount.second     = app.inputs.ntr-2;
                app.trialCount.first     = app.inputs.ntr-3;
            end
            
            % Plot
            app = PlotTrials(app);
            
            
            GetKeybordInput(app);
        end

        % Button pushed function: PreviousTrialsButton
        function previousTrials(app, event)
            % Setup the next 4 trials
            app.trialCount.first                 = app.trialCount.first-4;    
            app.trialCount.second                 = app.trialCount.second-4;
            app.trialCount.third                 = app.trialCount.third-4;
            app.trialCount.fourth                 = app.trialCount.fourth-4;
            
            if app.trialCount.first < 1    % Don't allow the count to go above the ntr.
                app.trialCount.fourth     = 4;
                app.trialCount.third     = 3;
                app.trialCount.second     = 2;
                app.trialCount.first     = 1;
            end
            
            % Plot
            app = PlotTrials(app);
            
            GetKeybordInput(app);
        end

        % Button pushed function: NextComponentButton
        function nextComponent(app, event)
            % Plot the first component trials x time.
            app.inputs.ic              = app.inputs.ic + 1;
            if app.inputs.ic > app.inputs.numComponents % Check if last comp.
                app.inputs.ic = app.inputs.numComponents;
            end
            
            app.inputs.trials_ica      = zeros(length(app.inputs.components.trial),app.inputs.numSamples);
            for tr = 1:app.inputs.ntr
                currentNumSamples                   = size(app.inputs.components.trial{tr},2);
                app.inputs.trials_ica(tr,1:currentNumSamples)  = app.inputs.components.trial{tr}(app.inputs.ic,:);
            end
            
            
            app = PlotTimeByTrial(app);
            
            app = PlotTopo(app);
            
            app = PlotTrials(app);
            
            app = PlotSpect(app);
            
            GetKeybordInput(app);
        end

        % Button pushed function: PreviousComponentButton
        function previousComponent(app, event)
            % Plot the first component trials x time.
            app.inputs.ic              = app.inputs.ic - 1;
            if app.inputs.ic < 1 % Check if last comp.
                app.inputs.ic = 1;
            end
            
            app.inputs.trials_ica      = zeros(length(app.inputs.components.trial),app.inputs.numSamples);
            for tr = 1:app.inputs.ntr
                currentNumSamples                   = size(app.inputs.components.trial{tr},2);
                app.inputs.trials_ica(tr,1:currentNumSamples)  = app.inputs.components.trial{tr}(app.inputs.ic,:);
            end
            
            app = PlotTimeByTrial(app);
            
            app = PlotTopo(app);
            
            app = PlotTrials(app);
            
            app = PlotSpect(app);
            
            GetKeybordInput(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TrialsPanel = uipanel(app.UIFigure);
            app.TrialsPanel.Title = 'Trials Panel';
            app.Trial1Axis = uiaxes(app.TrialsPanel);
            title(app.Trial1Axis, 'Title')
            xlabel(app.Trial1Axis, 'X')
            ylabel(app.Trial1Axis, 'Y')
            app.Trial2Axis = uiaxes(app.TrialsPanel);
            title(app.Trial2Axis, 'Title')
            xlabel(app.Trial2Axis, 'X')
            ylabel(app.Trial2Axis, 'Y')
            app.Trial3Axis = uiaxes(app.TrialsPanel);
            title(app.Trial3Axis, 'Title')
            xlabel(app.Trial3Axis, 'X')
            ylabel(app.Trial3Axis, 'Y')
            app.Trial4Axis = uiaxes(app.TrialsPanel);
            title(app.Trial4Axis, 'Title')
            xlabel(app.Trial4Axis, 'X')
            ylabel(app.Trial4Axis, 'Y')
            app.NextTrialsButton = uibutton(app.TrialsPanel, 'push');
            app.NextTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @nextTrials, true);
            app.NextTrialsButton.Text = '>';
            app.PreviousTrialsButton = uibutton(app.TrialsPanel, 'push');
            app.PreviousTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @previousTrials, true);
            app.PreviousTrialsButton.Text = '<';
            app.TimeXTrialPanel = uipanel(app.UIFigure);
            app.TimeXTrialPanel.Title = 'TimeXTrialPanel';
            app.Image = uiaxes(app.TimeXTrialPanel);
            title(app.Image, 'Title')
            xlabel(app.Image, 'Time (ms)')
            ylabel(app.Image, 'Trial')
            app.TopoplotPanel = uipanel(app.UIFigure);
            app.TopoplotPanel.Title = 'TopoplotPanel';
            app.NextComponentButton = uibutton(app.TopoplotPanel, 'push');
            app.NextComponentButton.ButtonPushedFcn = createCallbackFcn(app, @nextComponent, true);
            app.NextComponentButton.Text = 'Next ';
            app.PreviousComponentButton = uibutton(app.TopoplotPanel, 'push');
            app.PreviousComponentButton.ButtonPushedFcn = createCallbackFcn(app, @previousComponent, true);
            app.PreviousComponentButton.Text = 'Previous';
            app.TopoAxis = uiaxes(app.TopoplotPanel);
            app.TopoAxis.XAxisLocation = 'origin';
            app.SpectrogramPanel = uipanel(app.UIFigure);
            app.SpectrogramPanel.Title = 'Spectrogram';
            app.SpectrogramAxes = uiaxes(app.SpectrogramPanel);
            xlabel(app.SpectrogramAxes, 'X')
            ylabel(app.SpectrogramAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_plot_components(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end